classdef Gauss_seidel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        IteracionesEditField           matlab.ui.control.NumericEditField
        IteracionesEditFieldLabel      matlab.ui.control.Label
        UITable2                       matlab.ui.control.Table
        UITable                        matlab.ui.control.Table
        CalcularButton                 matlab.ui.control.Button
        Matriz                         matlab.ui.control.Button
        EcuacionesEditField            matlab.ui.control.EditField
        EcuacionesEditFieldLabel       matlab.ui.control.Label
        ButtonVectores                 matlab.ui.control.Button
        ButtonDimension                matlab.ui.control.Button
        TamaodelamatrizEditField       matlab.ui.control.NumericEditField
        TamaodelamatrizEditFieldLabel  matlab.ui.control.Label
        MtododeGaussSeidelLabel        matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global matrizSistema;
            matrizSistema=[];
        end

        % Button pushed function: ButtonDimension
        function ButtonDimensionPushed(app, event)
            numeroEcuaciones=app.TamaodelamatrizEditField.Value;
            app.ButtonDimension.Visible='off';
            app.TamaodelamatrizEditField.Editable='off';
            app.EcuacionesEditField.Visible="on";
            app.EcuacionesEditFieldLabel.Visible="on";
            app.ButtonVectores.Visible='on';
            app.EcuacionesEditFieldLabel.Text="Coeficientes ecuacion 1";
        end

        % Button pushed function: ButtonVectores
        function ButtonVectoresPushed(app, event)
            persistent contador;
            persistent matrizSistema;

            numeroEcuaciones=app.TamaodelamatrizEditField.Value;
            vectorStr=app.EcuacionesEditField.Value;
            vectorNum=str2num(vectorStr);
            
            if isempty(contador)
                matrizSistema=zeros(numeroEcuaciones,numeroEcuaciones+1);
                contador=0;
            end

            if numel(vectorNum)~=numeroEcuaciones+1
                warning('Error tamaño incorrecto');
            else
                contador=contador+1;
                matrizSistema(contador,:)=vectorNum;
                app.EcuacionesEditFieldLabel.Text="Coeficientes ecuacion "+(contador+1);

            end

            if contador==numeroEcuaciones
                app.ButtonVectores.Visible='off';
                app.EcuacionesEditField.Editable="off";
                app.EcuacionesEditFieldLabel.Text="Coeficientes ecuacion "+(contador);
                app.Matriz.Visible='on';
        
            end
            app.EcuacionesEditField.Value="";
            app.ButtonVectores.UserData.miMatriz = matrizSistema;
        end

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
            numeroVariables=app.TamaodelamatrizEditField.Value;
            iteraciones=app.IteracionesEditField.Value;
            x=zeros(1,numeroVariables);
            z=zeros(1,numeroVariables);
            sum=0;
            matriz = app.ButtonVectores.UserData.miMatriz;
            % Generar vectores vacío
            vectores_vacios = cell(1, numeroVariables);
            for i = 1:numeroVariables
                vectores_vacios{i} = [];
            end

            % Configurar tabla
            app.UITable.Data = cell(numeroVariables, iteraciones);
            app.UITable2.Data = cell(numeroVariables, iteraciones);
            app.UITable.RowName = cell(1, numeroVariables);
            nombres_columnas = cell(1, iteraciones);
            nombres_errores = cell(1, iteraciones);
            for i = 1:numeroVariables
                app.UITable.RowName{i} = ['X ' num2str(i)];
                app.UITable.Data{1, i} = vectores_vacios{i};
                app.UITable.Data{2, i} = vectores_vacios{i};
                app.UITable.Data{3, i} = vectores_vacios{i};
            end
            for i = 1:iteraciones
                nombres_columnas{i} = ['Iteracion ' num2str(i)];
            end
            for i = 1:iteraciones
                nombres_errores{i} = ['Error ' num2str(i)];
            end
            app.UITable.ColumnName=nombres_columnas;
             app.UITable2.ColumnName=nombres_errores;
            for i = 1:numeroVariables
                app.UITable2.RowName{i} = ['X ' num2str(i)];
                app.UITable2.Data{1, i} = vectores_vacios{i};
                app.UITable2.Data{2, i} = vectores_vacios{i};
                app.UITable2.Data{3, i} = vectores_vacios{i};
            end
            cont=1;
            %inicia contador en 1
            w=x;
            %guarda el vector inicial en una nueva variable
            while cont<=iteraciones
                % ejecucion de el ciclo para el numero de iteraciones
                for i=1:numeroVariables
                    %inicio ciclo for para recorrer las filas de la matriz

                    for j=1:numeroVariables
                        %inicio ciclo for para recorrer las columnas de la matriz

                        if i==j
                            %condicion para almacenar los terminos de cada diagonal
                            aii=matriz(i,j);
                            %almacena el valor de la diagonal en cada fila en aii
                        else

                            sum=sum+matriz(i,j)*x(j);
                            %suma la multiplicacion de cada termino de la matriz con el
                            %valor de x en la posicion j del vector solucion

                        end


                    end
                  
                    x(i)=((matriz(i,numeroVariables+1))-sum)/aii;
                    %almacena el vector resultante en una variable w para que este no
                    %cambie al realizar las aproximaciones
                    z(i)=abs((x(i)-w(i))/x(i))*100;
                    
                    sum=0;
                    %inicializa la suma en cero

                end

                tablaIte(:,cont)=x;         
                tablaErrores(:,cont)=z;
                tablaErrores(:,1)=NaN(1,numeroVariables);

                
                cont=cont+1;
                %aumento del contador
                w=x;
                %guarda el vector nuevo en el vector x
               
            end


            % Ubicar la fila correspondiente en la tabla y asignar los valores del vector


            % Actualizar la propiedad "Data" de la tabla
            app.UITable.Data= tablaIte;
            app.UITable2.Data=tablaErrores;
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            
        end

        % Value changed function: EcuacionesEditField
        function EcuacionesEditFieldValueChanged(app, event)
            value = app.EcuacionesEditField.Value;
            
        end

        % Value changing function: EcuacionesEditField
        function EcuacionesEditFieldValueChanging(app, event)
            changingValue = event.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.MtododeGaussSeidelLabel = uilabel(app.GridLayout);
            app.MtododeGaussSeidelLabel.Text = 'Método de Gauss Seidel';
            app.TamaodelamatrizEditFieldLabel = uilabel(app.GridLayout);
            app.TamaodelamatrizEditFieldLabel.Text = 'Tamaño de la matriz';
            app.TamaodelamatrizEditField = uieditfield(app.GridLayout, 'numeric');
            app.ButtonDimension = uibutton(app.GridLayout, 'push');
            app.ButtonDimension.ButtonPushedFcn = createCallbackFcn(app, @ButtonDimensionPushed, true);
            app.ButtonDimension.Text = 'Guardar';
            app.ButtonVectores = uibutton(app.GridLayout, 'push');
            app.ButtonVectores.ButtonPushedFcn = createCallbackFcn(app, @ButtonVectoresPushed, true);
            app.ButtonVectores.Visible = 'off';
            app.ButtonVectores.Text = 'Guardar';
            app.EcuacionesEditFieldLabel = uilabel(app.GridLayout);
            app.EcuacionesEditFieldLabel.Visible = 'off';
            app.EcuacionesEditFieldLabel.Text = 'Ecuaciones';
            app.EcuacionesEditField = uieditfield(app.GridLayout, 'text');
            app.EcuacionesEditField.ValueChangedFcn = createCallbackFcn(app, @EcuacionesEditFieldValueChanged, true);
            app.EcuacionesEditField.ValueChangingFcn = createCallbackFcn(app, @EcuacionesEditFieldValueChanging, true);
            app.EcuacionesEditField.Visible = 'off';
            app.Matriz = uibutton(app.GridLayout, 'push');
            app.Matriz.Visible = 'off';
            app.Matriz.Text = 'Mostrar Matriz';
            app.CalcularButton = uibutton(app.GridLayout, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = 'Calcular';
            app.UITable = uitable(app.GridLayout);
            app.UITable.ColumnName = {'Iteracion 1'; 'Iteracion 2'; 'Iteracion 3'; 'Iteracion 4'; 'Iteracion 5'; 'Iteracion 6'; 'Iteracion 7'};
            app.UITable.RowName = {};
            app.UITable2 = uitable(app.GridLayout);
            app.UITable2.ColumnName = {'Error 1'; 'Error 2'; 'Error 3'; 'Error 4'; 'Error 5'; 'Error 6'; 'Error 7'};
            app.UITable2.RowName = {};
            app.IteracionesEditFieldLabel = uilabel(app.GridLayout);
            app.IteracionesEditFieldLabel.Text = 'Iteraciones';
            app.IteracionesEditField = uieditfield(app.GridLayout, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Gauss_seidel

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end