classdef RK_3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        RungeKuttadetercerOrdenLabel  matlab.ui.control.Label
        hEditField                    matlab.ui.control.NumericEditField
        ValosdehLabel                 matlab.ui.control.Label
        xfEditField                   matlab.ui.control.NumericEditField
        ValorfinaldexLabel            matlab.ui.control.Label
        y0EditField                   matlab.ui.control.NumericEditField
        ValorinicialdeYLabel          matlab.ui.control.Label
        x0EditField                   matlab.ui.control.NumericEditField
        ValorInicialdeXLabel          matlab.ui.control.Label
        CalcularButton                matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function [x_vals, y_vals] = rungeKuttaTercerOrden(app, x0, y0, xf, h)
             % Función que representa la ecuación diferencial
            f = @(x, y) x * y; % Esta es la ecuación diferencial que deseas resolver
            
            % Inicializar vectores de valores x e y
            x_vals = x0:h:xf;
            y_vals = zeros(size(x_vals));
            y_vals(1) = y0;

            % Implementación del método de Runge-Kutta de tercer orden
            for i = 1:length(x_vals)-1
                x = x_vals(i);
                y = y_vals(i);
                k1 = h * f(x, y);
                k2 = h * f(x + h/2, y + k1/2);
                k3 = h * f(x + h, y - k1 + 2*k2);
                y_vals(i+1) = y + (k1 + 4*k2 + k3)/6;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)

            % Obtener valores de los campos de entrada
            x0 = app.x0EditField.Value;
            y0 = app.y0EditField.Value;
            xf = app.xfEditField.Value;
            h = app.hEditField.Value;
            
            % Calcular valores de x e y usando Runge-Kutta de tercer orden
            [x_vals, y_vals] = app.rungeKuttaTercerOrden(x0, y0, xf, h);
            
            % Mostrar resultados en la App Designer
            plot(app.UIAxes, x_vals, y_vals);
            xlabel(app.UIAxes, 'x');
            ylabel(app.UIAxes, 'y');
            title(app.UIAxes, 'Gráfica de la Ecuación Diferencial');


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Runge-Kutta')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.CalcularButton = uibutton(app.GridLayout, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = 'Calcular';
            app.ValorInicialdeXLabel = uilabel(app.GridLayout);
            app.ValorInicialdeXLabel.Text = 'Valor Inicial de X';
            app.x0EditField = uieditfield(app.GridLayout, 'numeric');
            app.ValorinicialdeYLabel = uilabel(app.GridLayout);
            app.ValorinicialdeYLabel.Text = 'Valor inicial de Y';
            app.y0EditField = uieditfield(app.GridLayout, 'numeric');
            app.ValorfinaldexLabel = uilabel(app.GridLayout);
            app.ValorfinaldexLabel.Text = 'Valor final de x';
            app.xfEditField = uieditfield(app.GridLayout, 'numeric');
            app.ValosdehLabel = uilabel(app.GridLayout);
            app.ValosdehLabel.Text = 'Valos de h';
            app.hEditField = uieditfield(app.GridLayout, 'numeric');
            app.RungeKuttadetercerOrdenLabel = uilabel(app.GridLayout);
            app.RungeKuttadetercerOrdenLabel.Text = 'Runge-Kutta de tercer Orden';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RK_3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end