classdef Simpson_3_8 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        ResultadoEditField              matlab.ui.control.EditField
        ResultadoEditFieldLabel         matlab.ui.control.Label
        UITable2_2                      matlab.ui.control.Table
        Simpson38mltipleLabel           matlab.ui.control.Label
        nmerodesegmentosEditField       matlab.ui.control.NumericEditField
        nmerodesegmentosEditFieldLabel  matlab.ui.control.Label
        CalcularButton                  matlab.ui.control.StateButton
        FuncinEditField                 matlab.ui.control.EditField
        IngreselafuncinLabel            matlab.ui.control.Label
        IngreselimsupEditField          matlab.ui.control.NumericEditField
        IngreselimsupLabel              matlab.ui.control.Label
        IngreseliminfEditField          matlab.ui.control.NumericEditField
        IngreseliminfLabel              matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: CalcularButton
        function CalcularButtonValueChanged(app, event)
             try
                % Extraer la función del campo de entrada
                funcion = app.FuncinEditField.Value;
                funcion = strrep(funcion, '^', '.^');  % Reemplazar ^ con .^ para operaciones elemento a elemento
                funcion = strrep(funcion, '*', '.*');  % Reemplazar * con .* para operaciones elemento a elemento
                funcion = strrep(funcion, '/', './');  % Reemplazar / con ./ para operaciones elemento a elemento
                
                f = str2func(['@(x)' funcion]); % Crear función anónima

                % Extraer los límites del intervalo y el número de segmentos
                a = app.IngreseliminfEditField.Value;
                b = app.IngreselimsupEditField.Value;
                n = app.nmerodesegmentosEditField.Value;

                % Verificar que los valores sean válidos
                if a >= b
                    error('El límite inferior debe ser menor que el límite superior.');
                end

                if n <= 0 || mod(n, 1) ~= 0
                    error('El número de segmentos debe ser un entero positivo.');
                end

                % Calcular el método de Simpson 3/8
                h = (b - a) / n;
                x = a:h:b;
                y = f(x);
                result = (3 * h / 8) * (y(1) + 3 * sum(y(2:3:end-2)) + 3 * sum(y(3:3:end-1)) + 2 * sum(y(4:3:end-3)) + y(end));

                % Mostrar el resultado
                app.ResultadoEditField.Value = num2str(result);

                % Actualizar la tabla con los valores de x y f(x)
                data = [x' y'];
                app.UITable2_2.Data = data;

                % Graficar la función evaluada
                plot(app.UIAxes, x, y);
                title(app.UIAxes, 'Simpson 3/8 Method');
                xlabel(app.UIAxes, 'x');
                ylabel(app.UIAxes, 'f(x)');
            catch ME
                % Mostrar el error en caso de que algo falle
                uialert(app.UIFigure, ME.message, 'Error');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.IngreseliminfLabel = uilabel(app.GridLayout);
            app.IngreseliminfLabel.Text = 'Ingrese lim inf: ';
            app.IngreseliminfEditField = uieditfield(app.GridLayout, 'numeric');
            app.IngreseliminfEditField.Tag = 'a';
            app.IngreselimsupLabel = uilabel(app.GridLayout);
            app.IngreselimsupLabel.Text = 'Ingrese lim sup: ';
            app.IngreselimsupEditField = uieditfield(app.GridLayout, 'numeric');
            app.IngreselimsupEditField.Tag = 'b';
            app.IngreselafuncinLabel = uilabel(app.GridLayout);
            app.IngreselafuncinLabel.Text = 'Función:  ';
            app.FuncinEditField = uieditfield(app.GridLayout, 'text');
            app.FuncinEditField.Tag = 'fdex';
            app.CalcularButton = uibutton(app.GridLayout, 'state');
            app.CalcularButton.ValueChangedFcn = createCallbackFcn(app, @CalcularButtonValueChanged, true);
            app.CalcularButton.Tag = 'btnCalcular';
            app.CalcularButton.Text = 'Calcular';
            app.nmerodesegmentosEditFieldLabel = uilabel(app.GridLayout);
            app.nmerodesegmentosEditFieldLabel.Text = 'número de segmentos';
            app.nmerodesegmentosEditField = uieditfield(app.GridLayout, 'numeric');
            app.Simpson38mltipleLabel = uilabel(app.GridLayout);
            app.Simpson38mltipleLabel.Text = 'Simpson 3/8 múltiple';
            app.UITable2_2 = uitable(app.GridLayout);
            app.UITable2_2.ColumnName = {'x'; 'f(x)'};
            app.UITable2_2.RowName = {};
            app.UITable2_2.ColumnEditable = true;
            app.ResultadoEditFieldLabel = uilabel(app.GridLayout);
            app.ResultadoEditFieldLabel.Text = 'Resultado';
            app.ResultadoEditField = uieditfield(app.GridLayout, 'text');
            app.ResultadoEditField.Tag = 'fdex';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Simpson_3_8

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end