classdef ajuste_cuadratico < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        AjusteCuadrticoTextArea       matlab.ui.control.TextArea
        AjusteCuadrticoTextAreaLabel  matlab.ui.control.Label
        ResultTable                   matlab.ui.control.Table
        YDataEditField                matlab.ui.control.EditField
        DatosenYLabel                 matlab.ui.control.Label
        XDataEditField                matlab.ui.control.EditField
        XLabel                        matlab.ui.control.Label
        NumPointsEditField            matlab.ui.control.NumericEditField
        iteraccionesLabel             matlab.ui.control.Label
        CalculateButton               matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalcularButtonPushed(app, event)
           try
                % Obtener los datos de entrada como cadenas de texto
                xDataStr = app.XDataEditField.Value;
                yDataStr = app.YDataEditField.Value;

                % Convertir los datos de texto a matrices numéricas
                xData = str2num(xDataStr);
                yData = str2num(yDataStr);

                % Verificar la validez de los datos
                if isempty(xData) || isempty(yData) || length(xData) ~= length(yData)
                    error('Por favor, ingrese datos válidos para X y Y.');
                end

                % Ajuste cuadrático
                X = [ones(length(xData), 1), xData(:), xData(:).^2];
                coeffs = X \ yData(:);
                c = coeffs(1);
                b = coeffs(2);
                a = coeffs(3);

                % Evaluar la función ajustada
                yFit = a*xData.^2 + b*xData + c;

                % Calcular los valores adicionales
                xSquared = xData.^2;
                xCubed = xData.^3;
                xFourth = xData.^4;
                xy = xData .* yData;
                xSquaredY = xSquared .* yData;
                residuals = yData - yFit;
                residualsSquared = residuals.^2;

                % Mostrar los resultados en la tabla
                app.ResultTable.Data = table(xData', yData', xSquared', xCubed', xFourth', xy', xSquaredY', ...
                    yFit', residuals', residualsSquared', ...
                    'VariableNames', {'x', 'f(x)', 'x^2', 'x^3', 'x^4', 'xy', 'x^2y', 'y*', 'e=y-y*', 'e^2'});

                % Graficar los datos originales y la función ajustada
                plot(app.UIAxes, xData, yData, 'bo');
                hold(app.UIAxes, 'on');
                plot(app.UIAxes, xData, yFit, 'r-');
                title(app.UIAxes, 'Ajuste Cuadrático');
                xlabel(app.UIAxes, 'x');
                ylabel(app.UIAxes, 'f(x)');
                legend(app.UIAxes, 'Datos', 'Ajuste Cuadrático');
                hold(app.UIAxes, 'off');
            catch ME
                % Mostrar un mensaje de error si algo falla
                uialert(app.UIFigure, ME.message, 'Error');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Ajuste Cudrático')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.CalculateButton = uibutton(app.GridLayout, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalculateButton.Text = 'Calcular';
            app.iteraccionesLabel = uilabel(app.GridLayout);
            app.iteraccionesLabel.Text = 'iteracciones';
            app.NumPointsEditField = uieditfield(app.GridLayout, 'numeric');
            app.XLabel = uilabel(app.GridLayout);
            app.XLabel.Text = 'Datos en X';
            app.XDataEditField = uieditfield(app.GridLayout, 'text');
            app.DatosenYLabel = uilabel(app.GridLayout);
            app.DatosenYLabel.Text = 'Datos en Y';
            app.YDataEditField = uieditfield(app.GridLayout, 'text');
            app.ResultTable = uitable(app.GridLayout);
            app.ResultTable.ColumnName = {'x'; 'y'; 'x^2'; 'x^3'; 'x^4'; 'xy'; 'x^2y'; 'y*'; 'e=y-y*'; 'e^2'};
            app.ResultTable.RowName = {};
            app.AjusteCuadrticoTextAreaLabel = uilabel(app.GridLayout);
            app.AjusteCuadrticoTextAreaLabel.Text = 'Ajuste Cuadrático';
            app.AjusteCuadrticoTextArea = uitextarea(app.GridLayout);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ajuste_cuadratico

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end