classdef ajuste_exponencial < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        UITable                         matlab.ui.control.Table
        AjusteExponencialLabel          matlab.ui.control.Label
        AjusteexponencialTextArea       matlab.ui.control.TextArea
        AjusteexponencialTextAreaLabel  matlab.ui.control.Label
        EditFieldY                      matlab.ui.control.EditField
        DatosdeYEditFieldLabel          matlab.ui.control.Label
        EditFieldX                      matlab.ui.control.EditField
        DatosdeXEditFieldLabel          matlab.ui.control.Label
        RealizarAjusteButton            matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RealizarAjusteButton
        function CalcularButtonValueChanged(app, event)

             % Obtener datos de los campos de texto y convertirlos a vectores numéricos
            x_data = str2num(app.EditFieldX.Value); %#ok<ST2NM>
            y_data = str2num(app.EditFieldY.Value); %#ok<ST2NM>

            % Verificar que los datos de entrada no estén vacíos y tengan el mismo tamaño
            if isempty(x_data) || isempty(y_data) || length(x_data) ~= length(y_data)
                app.AjusteexponencialTextArea.Value = "Error: Datos de entrada inválidos.";
                return;
            end

            % Calcular sumatorias necesarias
            n = length(x_data);
            sum_x = sum(x_data);
            sum_y = sum(y_data);
            sum_x2 = sum(x_data.^2);
            sum_xy = sum(x_data .* y_data);

            % Calcular determinantes
            det_main = n * sum_x2 - sum_x^2;
            det_a = sum_y * sum_x2 - sum_x * sum_xy;
            det_b = n * sum_xy - sum_x * sum_y;

            % Calcular los coeficientes a y b
            a = det_a / det_main;
            b = det_b / det_main;

            % Generar valores ajustados usando y* = ab^x
            y_fit = a * b .^ x_data;

            % Calcular los valores para cada columna de la tabla
            V = log(y_data);
            x2 = x_data .^ 2;
            xV = x_data .* V;
            e = y_data - y_fit;
            e2 = e .^ 2;

            % Llenar la tabla con los valores calculados
            data = [x_data', y_data', V', x2', xV', y_fit', e', e2'];
            app.UITable.Data = data;

            % Mostrar los parámetros del ajuste en el Text Area
            app.AjusteexponencialTextArea.Value = sprintf('Ajuste Exponencial: y = %.4f * %.4f^x', a, b);

            % Graficar los datos y el ajuste
            plot(app.UIAxes, x_data, y_data, 'o', 'DisplayName', 'Datos');
            hold(app.UIAxes, 'on');
            plot(app.UIAxes, x_data, y_fit, '-', 'DisplayName', 'Ajuste');
            hold(app.UIAxes, 'off');
            legend(app.UIAxes, 'show');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Ajuste Exponencial')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.RealizarAjusteButton = uibutton(app.GridLayout, 'push');
            app.RealizarAjusteButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonValueChanged, true);
            app.RealizarAjusteButton.Text = 'Realizar Ajuste';
            app.DatosdeXEditFieldLabel = uilabel(app.GridLayout);
            app.DatosdeXEditFieldLabel.Text = 'Datos de X';
            app.EditFieldX = uieditfield(app.GridLayout, 'text');
            app.DatosdeYEditFieldLabel = uilabel(app.GridLayout);
            app.DatosdeYEditFieldLabel.Text = 'Datos de Y';
            app.EditFieldY = uieditfield(app.GridLayout, 'text');
            app.AjusteexponencialTextAreaLabel = uilabel(app.GridLayout);
            app.AjusteexponencialTextAreaLabel.Text = 'Ajuste exponencial';
            app.AjusteexponencialTextArea = uitextarea(app.GridLayout);
            app.AjusteExponencialLabel = uilabel(app.GridLayout);
            app.AjusteExponencialLabel.Text = 'Ajuste Exponencial';
            app.UITable = uitable(app.GridLayout);
            app.UITable.ColumnName = {'x'; 'y'; 'V = ln y'; 'x^2'; 'xV'; 'y*'; 'e=y-y*'; 'e^2'};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ajuste_exponencial

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end