classdef biseccionM < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        ValorRealEditField        matlab.ui.control.NumericEditField
        ValorRealEditFieldLabel   matlab.ui.control.Label
        MtododebiseccinLabel      matlab.ui.control.Label
        CriteriodeparadaDropDown  matlab.ui.control.DropDown
        CriteriodeparadaLabel     matlab.ui.control.Label
        UITable2                  matlab.ui.control.Table
        UITable                   matlab.ui.control.Table
        ErrorEditField            matlab.ui.control.NumericEditField
        ErrorEditFieldLabel       matlab.ui.control.Label
        CalcularButton            matlab.ui.control.StateButton
        IngreselafuncinEditField  matlab.ui.control.EditField
        IngreselafuncinLabel      matlab.ui.control.Label
        IngreselimsupEditField    matlab.ui.control.NumericEditField
        IngreselimsupLabel        matlab.ui.control.Label
        IngreseliminfEditField    matlab.ui.control.NumericEditField
        IngreseliminfLabel        matlab.ui.control.Label
        UIAxes                    matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: CalcularButton
        function CalcularButtonValueChanged(app, event)

            format long;

            %Guarda en variable el valor del campo de texto
            funcion=app.IngreselafuncinEditField.Value
            %se define una funcion inline
            f=inline(funcion);
            %Se toman los valores de los campos de textos del lim inf,
            % lim sup y el valor del error.
            a=app.IngreseliminfEditField.Value; %limite inferior intervalo
            b=app.IngreselimsupEditField.Value; %limite superior intervalo
            errorEsperado = app.ErrorEditField.Value;
            %condición teorema de bolzano
            if f(a)*f(b) >0
                warndlg('Ingrese otro Intervalo','Error Teorema','modal');
            else
                %Crea vectores para la tabla de iteraciones
                it=[];xi=[];xr=[];xs=[];fxi=[];fxr=[];fxs=[];errorE=[];
                %Convierte vectores en tabla
                tablaIte=table(it,xi,xr,xs,fxi,fxr,fxs);
                tablaError=table(it,errorE);
                %inicia contador para registrar itereación
                cont =1;
                c=(a+b)/2;
                tablaIte(cont,:)={cont,a,c,b,f(a),f(c),f(b)};
                criterio = app.CriteriodeparadaDropDown.Value
                if f(a)*f(c)<0
                        b=c;
                    elseif f(a)*f(c)>0
                        a=c;
                end
                xr_1=c;
                cont = cont +1;
                switch criterio
                    case 'Error normalizado'        
                        errorN=100;
                        tablaError(1,:)={1,NaN};
                        while (errorN > errorEsperado)
                            c=(a+b) /2;
                            errorN=abs((c-xr_1)/c)*100;
                            errorN = str2double(num2str(round(errorN, 6), '%0.6f'));
                            xr_1=c;
                            tablaIte(cont,:)={cont,a,c,b,f(a),f(c),f(b)};
                            tablaError(cont,:)={cont,errorN};
                            if f(a)*f(c) <0
                                b=c ;
                            elseif f(a)*f(c)>0
                                a=c ;
                            else
                                break
                            end
                            
                           cont = cont +1;
                        end

                    case 'Error real'

                        valorReal= app.ValorRealEditField.Value;
                        errorR=abs ((valorReal-c)/valorReal)*100;
                        tablaError(1,:)={1,errorR};

                       
                        while (errorR>errorEsperado )
                            c=(a+b)/2;
                            errorR=abs ((valorReal-c)/valorReal*100);
                            tablaError(cont,:)={cont,errorR};
                            tablaIte(cont,:)={cont,a,c,b,f(a),f(c),f(b)};
                            
                            if f(a)*f(c) <0
                                b=c ;

                            elseif f(a)*f(c)>0
                                a=c;
                            else
                                break;
                            end
                            
                            cont = cont +1;
                           
                        end


                    case 'Función evaluada'

                        error=abs(f(c)); 
                        tablaError(1,:)={1,error}; 
                        tablaIte(1,:)={1,a,c,b,f(a),f(c),f(b)};
                        %cont=cont+1;
                        while ((error) > errorEsperado)
                            c=(a+b) /2;round(c,6);
                             
                            error=abs(f(c));
                            
                           tablaError(cont,:)={cont,error};
                            tablaIte(cont,:)={cont,a,c,b,f(a),f(c),f(b)};
                            if f(a)*f(c) <0
                                b=c ;
                            elseif f(a)*f(c)>0
                                a=c ;
                            else
                                break;
                            end
                            cont = cont +1;
                            
                        end
                        %tablaError(cont,:)={cont,error};
                end

                app.UITable.Data=tablaIte;
                app.UITable2.Data=tablaError
                ezplot(app.UIAxes,f,[a b])
            end


        end

        % Value changed function: CriteriodeparadaDropDown
        function CriteriodeparadaDropDownValueChanged(app, event)
            value = app.CriteriodeparadaDropDown.Value;
            bandera = strcmp(value,'Error real')
            if bandera==true
                app.ValorRealEditFieldLabel.Visible='on'
                app.ValorRealEditField.Visible='on'
            else
                app.ValorRealEditFieldLabel.Visible='off'
                app.ValorRealEditField.Visible='off'
            end
        end

        % Clicked callback: CriteriodeparadaDropDown
        function CriteriodeparadaDropDownClicked(app, event)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Función')
            xlabel(app.UIAxes, 'x')
            ylabel(app.UIAxes, 'f(x)')
            zlabel(app.UIAxes, 'Z')
            app.IngreseliminfLabel = uilabel(app.GridLayout);
            app.IngreseliminfLabel.Text = 'Ingrese lim inf: ';
            app.IngreseliminfEditField = uieditfield(app.GridLayout, 'numeric');
            app.IngreseliminfEditField.Tag = 'a';
            app.IngreselimsupLabel = uilabel(app.GridLayout);
            app.IngreselimsupLabel.Text = 'Ingrese lim sup: ';
            app.IngreselimsupEditField = uieditfield(app.GridLayout, 'numeric');
            app.IngreselimsupEditField.Tag = 'b';
            app.IngreselafuncinLabel = uilabel(app.GridLayout);
            app.IngreselafuncinLabel.Text = 'Ingrese la función:  ';
            app.IngreselafuncinEditField = uieditfield(app.GridLayout, 'text');
            app.IngreselafuncinEditField.Tag = 'fdex';
            app.CalcularButton = uibutton(app.GridLayout, 'state');
            app.CalcularButton.ValueChangedFcn = createCallbackFcn(app, @CalcularButtonValueChanged, true);
            app.CalcularButton.Tag = 'btnCalcular';
            app.CalcularButton.Text = 'Calcular';
            app.ErrorEditFieldLabel = uilabel(app.GridLayout);
            app.ErrorEditFieldLabel.Text = 'Error';
            app.ErrorEditField = uieditfield(app.GridLayout, 'numeric');
            app.UITable = uitable(app.GridLayout);
            app.UITable.ColumnName = {'it'; 'xi'; 'xr'; 'xs'; 'f(xi)'; 'f(xr)'; 'f(xs)'};
            app.UITable.RowName = {};
            app.UITable2 = uitable(app.GridLayout);
            app.UITable2.ColumnName = {'It'; 'Error'};
            app.UITable2.RowName = {};
            app.UITable2.ColumnEditable = true;
            app.CriteriodeparadaLabel = uilabel(app.GridLayout);
            app.CriteriodeparadaLabel.Text = 'Criterio de parada: ';
            app.CriteriodeparadaDropDown = uidropdown(app.GridLayout);
            app.CriteriodeparadaDropDown.Items = {'Error normalizado', 'Error real', 'Función evaluada'};
            app.CriteriodeparadaDropDown.ValueChangedFcn = createCallbackFcn(app, @CriteriodeparadaDropDownValueChanged, true);
            app.CriteriodeparadaDropDown.ClickedFcn = createCallbackFcn(app, @CriteriodeparadaDropDownClicked, true);
            app.CriteriodeparadaDropDown.Value = 'Error normalizado';
            app.MtododebiseccinLabel = uilabel(app.GridLayout);
            app.MtododebiseccinLabel.Text = 'Método de bisección';
            app.ValorRealEditFieldLabel = uilabel(app.GridLayout);
            app.ValorRealEditFieldLabel.Visible = 'off';
            app.ValorRealEditFieldLabel.Text = 'Valor Real: ';
            app.ValorRealEditField = uieditfield(app.GridLayout, 'numeric');
            app.ValorRealEditField.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = biseccionM

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end