classdef interpolacion_newton < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        ResultadoLabel       matlab.ui.control.Label
        CalcularButton       matlab.ui.control.Button
        TablaDatos           matlab.ui.control.Table
        UIAxesInterpolacion  matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function y = newtonInterpolation(app, x, y, xi)
           % Newton Interpolation
            n = length(x);
            D = zeros(n, n);
            D(:,1) = y(:); % First column is y
            
            for j = 2:n
                for i = j:n
                    D(i,j) = (D(i,j-1) - D(i-1,j-1)) / (x(i) - x(i-j+1));
                end
            end
            
            % Interpolating value at xi
            yi = D(n,n);
            for k = n-1:-1:1
                yi = yi * (xi - x(k)) + D(k,k);
            end
            y = yi;
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
           data = app.TablaDatos.Data;
            
            % Verificar si la tabla contiene al menos dos columnas
            if size(data, 2) < 2
                uialert(app.UIFigure, 'La tabla debe contener al menos dos columnas: x e y.', 'Error de Datos');
                return;
            end
            
            % Convertir los datos de la tabla a números
            x = str2double(data(:, 1));
            y = str2double(data(:, 2));
            
            % Verificar si los datos son numéricos y no contienen NaN
            if any(isnan(x)) || any(isnan(y))
                uialert(app.UIFigure, 'La tabla contiene valores no numéricos.', 'Error de Datos');
                return;
            end
            
            % Interpolating points for plotting
            xi = linspace(min(x), max(x), 100);
            yi = arrayfun(@(xii) app.newtonInterpolation(x, y, xii), xi);
            
            % Plotting the results
            plot(app.UIAxesInterpolacion, x, y, 'ro', 'MarkerSize', 8, 'DisplayName', 'Puntos de datos');
            hold(app.UIAxesInterpolacion, 'on');
            plot(app.UIAxesInterpolacion, xi, yi, 'b-', 'LineWidth', 1.5, 'DisplayName', 'Interpolación de Newton');
            hold(app.UIAxesInterpolacion, 'off');
            legend(app.UIAxesInterpolacion, 'show');
            
            % Mostrar diferencias divididas
            n = length(x);
            D = zeros(n, n);
            D(:,1) = y(:);
            for j = 2:n
                for i = j:n
                    D(i,j) = (D(i,j-1) - D(i-1,j-1)) / (x(i) - x(i-j+1));
                end
            end
            
            % Mostrar los coeficientes de diferencias divididas
            app.ResultadoLabel.Text = sprintf('Coeficientes: %s', mat2str(D(1, :), 3));
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxesInterpolacion = uiaxes(app.UIFigure);
            title(app.UIAxesInterpolacion, 'Title')
            xlabel(app.UIAxesInterpolacion, 'X')
            ylabel(app.UIAxesInterpolacion, 'Y')
            zlabel(app.UIAxesInterpolacion, 'Z')
            app.TablaDatos = uitable(app.UIFigure);
            app.TablaDatos.ColumnName = {'x'; 'y'};
            app.TablaDatos.RowName = {};
            app.CalcularButton = uibutton(app.UIFigure, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = 'Calcular';
            app.ResultadoLabel = uilabel(app.UIFigure);
            app.ResultadoLabel.Text = 'Resultado';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = interpolacion_newton

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end