classdef puntoFijoM < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        DerivadaEditField         matlab.ui.control.EditField
        DerivadaEditFieldLabel    matlab.ui.control.Label
        IngresegxEditField        matlab.ui.control.EditField
        IngresegxEditFieldLabel   matlab.ui.control.Label
        ValorRealEditField        matlab.ui.control.NumericEditField
        ValorRealEditFieldLabel   matlab.ui.control.Label
        MtododelpuntofijoLabel    matlab.ui.control.Label
        CriteriodeparadaDropDown  matlab.ui.control.DropDown
        CriteriodeparadaLabel     matlab.ui.control.Label
        UITable2                  matlab.ui.control.Table
        UITable                   matlab.ui.control.Table
        ErrorEditField            matlab.ui.control.NumericEditField
        ErrorEditFieldLabel       matlab.ui.control.Label
        CalcularButton            matlab.ui.control.StateButton
        IngresefxEditField        matlab.ui.control.EditField
        IngreselafuncinLabel      matlab.ui.control.Label
        IngresecondicioninicialEditField  matlab.ui.control.NumericEditField
        IngreselimsupLabel        matlab.ui.control.Label
        UIAxes                    matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: CalcularButton
        function CalcularButtonValueChanged(app, event)
            
            format long;

            % Guarda en variable el valor del campo de texto
            funcion = app.IngresefxEditField.Value;
            funciong = app.IngresegxEditField.Value;

            % Define funciones anonimas
            f = str2func(['@(x) ', funcion]);
            g = str2func(['@(x) ', funciong]);

            % Condicion inicial
            xn = app.IngresecondicioninicialEditField.Value; % limite superior intervalo
            errorEsperado = app.ErrorEditField.Value;

            % Calcula la derivada numericamente
            gp = @(x) (g(x + 1e-5) - g(x)) / 1e-5;
            gp_eval = gp(xn);
            
            % Muestra la derivada
            app.DerivadaEditField.Value = string(gp_eval);
            
            % Crea vectores para la tabla de iteraciones
            it = []; xi = []; fxi = []; dxi = []; errorE = [];
            tablaIte = table(it, xi, fxi, dxi);
            tablaError = table(it, errorE);

            cont = 1;
            if abs(gp_eval) >= 1
                warndlg('No cumple el criterio de convergenica', 'Error', 'modal');
            else
                a = f(xn);
                xn1 = g(xn);
                tablaIte(cont, :) = {cont, xn, xn1, a};

                criterio = app.CriteriodeparadaDropDown.Value;
                switch criterio
                    case 'Error normalizado'
                        errorN = abs((xn1 - xn) / xn1 * 100);
                        tablaError(1, :) = {1, NaN};
                        
                        while errorN > errorEsperado
                            xn = xn1;
                            a = f(xn);
                            xn1 = g(xn);
                            cont = cont + 1;
                            errorN = abs((xn1 - xn) / xn1 * 100);
                            tablaIte(cont, :) = {cont, xn, xn1, a};
                            tablaError(cont, :) = {cont, errorN};
                        end
                    case 'Error real'
                        valorReal = app.ValorRealEditField.Value;
                        errorR = abs((valorReal - xn) / valorReal) * 100;
                        
                        while errorR > errorEsperado
                            xn = xn1;
                            a = f(xn);
                            xn1 = g(xn);
                            cont = cont + 1;
                            errorR = abs((valorReal - xn) / valorReal) * 100;
                            tablaIte(cont, :) = {cont, xn, xn1, a};
                            tablaError(cont, :) = {cont, errorR};
                        end
                    case 'Función evaluada'
                        error = abs(f(xn));
                        
                        while error > errorEsperado
                            xn = xn1;
                            a = f(xn);
                            xn1 = g(xn);
                            cont = cont + 1;
                            error = abs(f(xn));
                            tablaIte(cont, :) = {cont, xn, xn1, a};
                            tablaError(cont, :) = {cont, error};
                        end
                end

                app.UITable.Data = tablaIte;
                app.UITable2.Data = tablaError;
            end
            
            i1 = double(xn) - 1;
            i2 = double(xn) + 1;
            fplot(app.UIAxes, f, [i1 i2]);
        
        end

        % Value changed function: CriteriodeparadaDropDown
        function CriteriodeparadaDropDownValueChanged(app, event)
            value = app.CriteriodeparadaDropDown.Value;
            bandera = strcmp(value,'Error real')
            if bandera==true
                app.ValorRealEditFieldLabel.Visible='on'
                app.ValorRealEditField.Visible='on'
            else
               app.ValorRealEditFieldLabel.Visible='off'
               app.ValorRealEditField.Visible='off'
            end
        end

        % Clicked callback: CriteriodeparadaDropDown
        function CriteriodeparadaDropDownClicked(app, event)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Función')
            xlabel(app.UIAxes, 'x')
            ylabel(app.UIAxes, 'f(x)')
            zlabel(app.UIAxes, 'Z')
            app.IngreselimsupLabel = uilabel(app.GridLayout);
            app.IngreselimsupLabel.Text = 'Ingrese condicion inicial: ';
            app.IngresecondicioninicialEditField = uieditfield(app.GridLayout, 'numeric');
            app.IngresecondicioninicialEditField.Tag = 'b';
            app.IngreselafuncinLabel = uilabel(app.GridLayout);
            app.IngreselafuncinLabel.Text = 'Ingrese f(x): ';
            app.IngresefxEditField = uieditfield(app.GridLayout, 'text');
            app.IngresefxEditField.Tag = 'fdex';
            app.CalcularButton = uibutton(app.GridLayout, 'state');
            app.CalcularButton.ValueChangedFcn = createCallbackFcn(app, @CalcularButtonValueChanged, true);
            app.CalcularButton.Tag = 'btnCalcular';
            app.CalcularButton.Text = 'Calcular';
            app.ErrorEditFieldLabel = uilabel(app.GridLayout);
            app.ErrorEditFieldLabel.Text = 'Error';
            app.ErrorEditField = uieditfield(app.GridLayout, 'numeric');
            app.UITable = uitable(app.GridLayout);
            app.UITable.ColumnName = {'it'; 'xi'; 'g(xi)'; 'f(xi)'};
            app.UITable.RowName = {};
            app.UITable2 = uitable(app.GridLayout);
            app.UITable2.ColumnName = {'It'; 'Error'};
            app.UITable2.RowName = {};
            app.UITable2.ColumnEditable = true;
            app.CriteriodeparadaLabel = uilabel(app.GridLayout);
            app.CriteriodeparadaLabel.Text = 'Criterio de parada: ';
            app.CriteriodeparadaDropDown = uidropdown(app.GridLayout);
            app.CriteriodeparadaDropDown.Items = {'Error normalizado', 'Error real', 'Función evaluada'};
            app.CriteriodeparadaDropDown.ValueChangedFcn = createCallbackFcn(app, @CriteriodeparadaDropDownValueChanged, true);
            app.CriteriodeparadaDropDown.ClickedFcn = createCallbackFcn(app, @CriteriodeparadaDropDownClicked, true);
            app.CriteriodeparadaDropDown.Value = 'Error normalizado';
            app.MtododelpuntofijoLabel = uilabel(app.GridLayout);
            app.MtododelpuntofijoLabel.Text = 'Método del punto fijo';
            app.ValorRealEditFieldLabel = uilabel(app.GridLayout);
            app.ValorRealEditFieldLabel.Visible = 'off';
            app.ValorRealEditFieldLabel.Text = 'Valor Real: ';
            app.ValorRealEditField = uieditfield(app.GridLayout, 'numeric');
            app.ValorRealEditField.Visible = 'off';
            app.IngresegxEditFieldLabel = uilabel(app.GridLayout);
            app.IngresegxEditFieldLabel.Text = 'Ingrese g(x)';
            app.IngresegxEditField = uieditfield(app.GridLayout, 'text');
            app.DerivadaEditFieldLabel = uilabel(app.GridLayout);
            app.DerivadaEditFieldLabel.Text = 'Derivada';
            app.DerivadaEditField = uieditfield(app.GridLayout, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = puntoFijoM

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end